classdef training_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RocketAppUIFigure             matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        SelectdirButton               matlab.ui.control.Button
        imagesListBoxLabel            matlab.ui.control.Label
        imagesListBox                 matlab.ui.control.ListBox
        TextArea                      matlab.ui.control.TextArea
        TrainingdatabrowsingappLabel  matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        UITable                       matlab.ui.control.Table
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        training_dir % Description
        data % Description
        imdata % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectdirButton
        function SelectdirButtonPushed(app, event)
%             app.training_dir = uigetdir;
            prompt = {'Enter a training directory'};
            dlgtitle = 'Training dir';
            definput = {'2021-03-06-1'};
            dims = [1 40];
            app.training_dir = inputdlg(prompt,dlgtitle,dims,definput)
            app.TextArea.Value = app.training_dir;
%             files = struct2table(dir(app.training_dir+"\*.png"))
            training_dataset = app.training_dir;
            data = readtable("D:\devel\AI-workflow\samples\"+training_dataset+"\data.csv");
            data = renamevars(data,["Var1","Var2","Var3","Var4","Var5","Var6"],["img", "x", "y", "r2", "l2", "r1"]);
            data.frame = erase(string(data.img),"samples/"+training_dataset+"/");
            app.data = data;
            app.imagesListBox.Items = data.frame;
        end

        % Value changed function: imagesListBox
        function imagesListBoxValueChanged(app, event)
            file = app.imagesListBox.Value;
            img = imread("D:\devel\AI-workflow\samples\"+app.training_dir+"\"+file);
            app.imdata = img;
            datatable = app.data;
            app.UITable.Data = datatable(datatable.frame == file,{'x','y','r2','l2'});
            imshow(img,"Parent",app.UIAxes)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.RocketAppUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RocketAppUIFigure = uifigure('Visible', 'off');
            app.RocketAppUIFigure.Name = 'Rocket App';
            app.RocketAppUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.RocketAppUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SelectdirButton = uibutton(app.LeftPanel, 'push');
            app.SelectdirButton.ButtonPushedFcn = createCallbackFcn(app, @SelectdirButtonPushed, true);
            app.SelectdirButton.Text = 'Select dir';
            app.imagesListBoxLabel = uilabel(app.LeftPanel);
            app.imagesListBoxLabel.Text = 'images';
            app.imagesListBox = uilistbox(app.LeftPanel);
            app.imagesListBox.Items = {};
            app.imagesListBox.ValueChangedFcn = createCallbackFcn(app, @imagesListBoxValueChanged, true);
            app.imagesListBox.Value = {};
            app.TextArea = uitextarea(app.LeftPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Enable = 'off';
            app.TrainingdatabrowsingappLabel = uilabel(app.LeftPanel);
            app.TrainingdatabrowsingappLabel.Text = 'Training data browsing app';
            app.RightPanel = uipanel(app.GridLayout);
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'x'; 'y'; 'r2'; 'l2'};
            app.UITable.RowName = {};
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Image')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.RocketAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = training_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RocketAppUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RocketAppUIFigure)
        end
    end
end